classdef BirdWing_2017b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LiftDragMeasurementUIFigure     matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        SetupScalesButton               matlab.ui.control.Button
        MeasureLiftDragButton           matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        Armwing_LmmEditFieldLabel       matlab.ui.control.Label
        Armwing_LmmEditField            matlab.ui.control.NumericEditField
        Armwing_DmmEditFieldLabel       matlab.ui.control.Label
        Armwing_DmmEditField            matlab.ui.control.NumericEditField
        Armscale_LmmEditFieldLabel      matlab.ui.control.Label
        Armscale_LmmEditField           matlab.ui.control.NumericEditField
        Armscale_DmmLabel               matlab.ui.control.Label
        Armscale_DmmEditField           matlab.ui.control.NumericEditField
        TareButton                      matlab.ui.control.Button
        AngleofAttackdegEditFieldLabel  matlab.ui.control.Label
        AngleofAttackdegEditField       matlab.ui.control.NumericEditField
        SaveMeasurementsButton          matlab.ui.control.Button
        WindspeedmsLabel                matlab.ui.control.Label
        WindspeedmsEditField            matlab.ui.control.NumericEditField
        MeasureWindSpeedButton          matlab.ui.control.Button
        SetupPitottubeButton            matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        WingtypeButtonGroup             matlab.ui.container.ButtonGroup
        ArtificialwingButton            matlab.ui.control.ToggleButton
        HawfinchlspeedButton            matlab.ui.control.ToggleButton
        HawfinchhspeedButton            matlab.ui.control.ToggleButton
    end


    properties (Access = private)
        s_L %serial handle scales
        s_D
        counter = 1;
        L
        D
        AoA = 0;
        AoAinput = 0;
        WindSpeed = 0;
        WindSpeedinput = 0;
        arduino
        PitotPin = 'A4'
        rho_air = 1.2041 %@20 degree Celcius
        convertArduinoPressure=500/5; %conversersion factor, 500 is set range on pressure transmitter
        gravity=9.81;
        rwL=1; %arm wing Lift component, set to 1 for direct scale output
        rsL=1; %arm scale Lift component, set to 1 for direct scale output
        rwD=1; %arm wing Drag component, set to 1 for direct scale output
        rsD=1; %arm scale Drag component, set to 1 for direct scale output
        plot_color = 'r'
        area_finch = 0.0046768; %square meter
        area_artificial = 0.0046768;
        area_wing = 0.0046768; %=area_artificial
        Cl = 1;
        Cd = 2;
        wing_type = 'Hawfinch'
    end


    methods (Access = private)

        % Button pushed function: SetupScalesButton
        function SetupScalesButtonPushed(app, event)
            %Setup scales and return handles
            [app.s_L,app.s_D]=SetupScales;
            app.Lamp.Enable = 'on';
        end

        % Button pushed function: MeasureLiftDragButton
        function MeasureLiftDragButtonPushed(app, event)
            %get read-out of scales
            [out_L,out_D]=readScales(app.s_L,app.s_D);
            hold(app.UIAxes,'on')
            app.L(1,app.counter)=((out_L/1000)*app.gravity)*(app.rsL/app.rwL);
            app.D(1,app.counter)=((out_D/1000)*app.gravity)*(app.rsD/app.rwD);
            app.AoA(1,app.counter)=app.AoAinput;
            app.WindSpeed(1,app.counter)=app.WindSpeedinput;
            app.Cl(1,app.counter)=2*app.L(1,app.counter)/(app.rho_air*(app.WindSpeedinput^2)*app.area_wing);
            app.Cd(1,app.counter)=2*app.D(1,app.counter)/(app.rho_air*(app.WindSpeedinput^2)*app.area_wing);
            scatter(app.UIAxes,app.AoA(1,app.counter),app.Cl(1,app.counter),'Marker','o','MarkerFaceColor',app.plot_color,'MarkerEdgeColor',app.plot_color)
            scatter(app.UIAxes,app.AoA(1,app.counter),app.Cd(1,app.counter),'Marker','o','MarkerEdgeColor',app.plot_color)
            scatter(app.UIAxes,app.AoA(1,app.counter),app.Cl(1,app.counter)/(10*app.Cd(1,app.counter)),'Marker','d','MarkerFaceColor',app.plot_color,'MarkerEdgeColor','k')
            hold(app.UIAxes,'off')
            legend(app.UIAxes,'Cl','Cd','Cl/10*Cd')
            app.counter=app.counter+1;
        end

        % Button pushed function: TareButton
        function TareButtonPushed(app, event)
            fopen(app.s_L);
            fprintf(app.s_L,'T');
            fclose(app.s_L);
            fopen(app.s_D);
            fprintf(app.s_D,'T');
            fclose(app.s_D);
        end

        % Value changed function: AngleofAttackdegEditField
        function AngleofAttackdegEditFieldValueChanged(app, event)
            app.AoAinput = app.AngleofAttackdegEditField.Value;
        end

        % Button pushed function: SaveMeasurementsButton
        function SaveMeasurementsButtonPushed(app, event)
            %Save measurement data
            Lift = app.L;
            Drag = app.D;
            AngleOfAtack = app.AoA;
            Speed = app.WindSpeed;
            uisave({'Lift','Drag','AngleOfAtack','Speed'},'LiftDragData');
        end

        % Value changed function: WindspeedmsEditField
        function WindspeedmsEditFieldValueChanged(app, event)
            app.WindSpeedinput = app.WindspeedmsEditField.Value;
        end

        % Button pushed function: MeasureWindSpeedButton
        function MeasureWindSpeedButtonPushed(app, event)
            PressureVoltage=readVoltage(app.arduino, app.PitotPin)
            Velo = sqrt((2*PressureVoltage*app.convertArduinoPressure)/app.rho_air);
            app.WindspeedmsEditField.Value = Velo;
            app.WindSpeedinput = Velo;
        end

        % Button pushed function: SetupPitottubeButton
        function SetupPitottubeButtonPushed(app, event)
            %Connect to arduino
            [app.arduino]=SetupPitotTube;
            app.Lamp_2.Enable = 'on';
        end

        % Value changed function: Armwing_LmmEditField
        function Armwing_LmmEditFieldValueChanged(app, event)
            app.rwL = app.Armwing_LmmEditField.Value;
            
        end

        % Value changed function: Armwing_DmmEditField
        function Armwing_DmmEditFieldValueChanged(app, event)
            app.rwD = app.Armwing_DmmEditField.Value;
            
        end

        % Value changed function: Armscale_LmmEditField
        function Armscale_LmmEditFieldValueChanged(app, event)
            app.rsL = app.Armscale_LmmEditField.Value;
            
        end

        % Value changed function: Armscale_DmmEditField
        function Armscale_DmmEditFieldValueChanged(app, event)
            app.rsD = app.Armscale_DmmEditField.Value;
            
        end

        % Selection changed function: WingtypeButtonGroup
        function WingtypeButtonGroupSelectionChanged(app, event)
            app.wing_type = app.WingtypeButtonGroup.SelectedObject;
            app.wing_type.Text
            if strcmpi(app.wing_type.Text,'Hawfinch l-speed')
                app.plot_color = 'b';
                app.area_wing = app.area_finch;
            elseif strcmpi(app.wing_type.Text,'Hawfinch h-speed')
                app.plot_color = 'g';
                app.area_wing = app.area_finch;
            else
                app.plot_color = 'r';
                app.area_wing = app.area_artificial;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LiftDragMeasurementUIFigure = uifigure;
            app.LiftDragMeasurementUIFigure.Name = 'Lift & Drag Measurement';
            app.UIAxes = uiaxes(app.LiftDragMeasurementUIFigure);
            title(app.UIAxes, 'Lift & Drag Coefficients')
            xlabel(app.UIAxes, 'Angle of Attack (deg)')
            ylabel(app.UIAxes, 'Coefficients')
            app.UIAxes.XLim = [-30 90];
            app.SetupScalesButton = uibutton(app.LiftDragMeasurementUIFigure, 'push');
            app.SetupScalesButton.ButtonPushedFcn = createCallbackFcn(app, @SetupScalesButtonPushed, true);
            app.SetupScalesButton.Text = 'Setup Scales';
            app.MeasureLiftDragButton = uibutton(app.LiftDragMeasurementUIFigure, 'push');
            app.MeasureLiftDragButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureLiftDragButtonPushed, true);
            app.MeasureLiftDragButton.Text = 'Measure Lift & Drag';
            app.Lamp = uilamp(app.LiftDragMeasurementUIFigure);
            app.Lamp.Enable = 'off';
            app.Armwing_LmmEditFieldLabel = uilabel(app.LiftDragMeasurementUIFigure);
            app.Armwing_LmmEditFieldLabel.Text = 'Arm wing_L (mm):';
            app.Armwing_LmmEditField = uieditfield(app.LiftDragMeasurementUIFigure, 'numeric');
            app.Armwing_LmmEditField.Limits = [0 Inf];
            app.Armwing_LmmEditField.ValueDisplayFormat = '%11.3g';
            app.Armwing_LmmEditField.ValueChangedFcn = createCallbackFcn(app, @Armwing_LmmEditFieldValueChanged, true);
            app.Armwing_DmmEditFieldLabel = uilabel(app.LiftDragMeasurementUIFigure);
            app.Armwing_DmmEditFieldLabel.Text = 'Arm wing_D (mm):';
            app.Armwing_DmmEditField = uieditfield(app.LiftDragMeasurementUIFigure, 'numeric');
            app.Armwing_DmmEditField.Limits = [0 Inf];
            app.Armwing_DmmEditField.ValueDisplayFormat = '%11.3g';
            app.Armwing_DmmEditField.ValueChangedFcn = createCallbackFcn(app, @Armwing_DmmEditFieldValueChanged, true);
            app.Armscale_LmmEditFieldLabel = uilabel(app.LiftDragMeasurementUIFigure);
            app.Armscale_LmmEditFieldLabel.Text = 'Arm scale_L (mm): ';
            app.Armscale_LmmEditField = uieditfield(app.LiftDragMeasurementUIFigure, 'numeric');
            app.Armscale_LmmEditField.Limits = [0 Inf];
            app.Armscale_LmmEditField.ValueDisplayFormat = '%11.3g';
            app.Armscale_LmmEditField.ValueChangedFcn = createCallbackFcn(app, @Armscale_LmmEditFieldValueChanged, true);
            app.Armscale_DmmLabel = uilabel(app.LiftDragMeasurementUIFigure);
            app.Armscale_DmmLabel.Text = 'Arm scale_D (mm):';
            app.Armscale_DmmEditField = uieditfield(app.LiftDragMeasurementUIFigure, 'numeric');
            app.Armscale_DmmEditField.Limits = [0 Inf];
            app.Armscale_DmmEditField.ValueDisplayFormat = '%11.3g';
            app.Armscale_DmmEditField.ValueChangedFcn = createCallbackFcn(app, @Armscale_DmmEditFieldValueChanged, true);
            app.TareButton = uibutton(app.LiftDragMeasurementUIFigure, 'push');
            app.TareButton.ButtonPushedFcn = createCallbackFcn(app, @TareButtonPushed, true);
            app.TareButton.Text = 'Tare';
            app.AngleofAttackdegEditFieldLabel = uilabel(app.LiftDragMeasurementUIFigure);
            app.AngleofAttackdegEditFieldLabel.Text = 'Angle of Attack (deg):';
            app.AngleofAttackdegEditField = uieditfield(app.LiftDragMeasurementUIFigure, 'numeric');
            app.AngleofAttackdegEditField.Limits = [-30 90];
            app.AngleofAttackdegEditField.ValueChangedFcn = createCallbackFcn(app, @AngleofAttackdegEditFieldValueChanged, true);
            app.SaveMeasurementsButton = uibutton(app.LiftDragMeasurementUIFigure, 'push');
            app.SaveMeasurementsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMeasurementsButtonPushed, true);
            app.SaveMeasurementsButton.Text = 'Save Measurements';
            app.WindspeedmsLabel = uilabel(app.LiftDragMeasurementUIFigure);
            app.WindspeedmsLabel.Text = 'Wind speed (m/s):';
            app.WindspeedmsEditField = uieditfield(app.LiftDragMeasurementUIFigure, 'numeric');
            app.WindspeedmsEditField.Limits = [0 100];
            app.WindspeedmsEditField.ValueChangedFcn = createCallbackFcn(app, @WindspeedmsEditFieldValueChanged, true);
            app.MeasureWindSpeedButton = uibutton(app.LiftDragMeasurementUIFigure, 'push');
            app.MeasureWindSpeedButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureWindSpeedButtonPushed, true);
            app.MeasureWindSpeedButton.Text = 'Measure Wind Speed';
            app.SetupPitottubeButton = uibutton(app.LiftDragMeasurementUIFigure, 'push');
            app.SetupPitottubeButton.ButtonPushedFcn = createCallbackFcn(app, @SetupPitottubeButtonPushed, true);
            app.SetupPitottubeButton.Text = 'Setup Pitot tube';
            app.Lamp_2 = uilamp(app.LiftDragMeasurementUIFigure);
            app.Lamp_2.Enable = 'off';
            app.WingtypeButtonGroup = uibuttongroup(app.LiftDragMeasurementUIFigure);
            app.WingtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WingtypeButtonGroupSelectionChanged, true);
            app.WingtypeButtonGroup.Title = 'Wing type';
            app.ArtificialwingButton = uitogglebutton(app.WingtypeButtonGroup);
            app.ArtificialwingButton.Text = 'Artificial wing';
            app.ArtificialwingButton.Value = true;
            app.HawfinchlspeedButton = uitogglebutton(app.WingtypeButtonGroup);
            app.HawfinchlspeedButton.Text = 'Hawfinch l-speed';
            app.HawfinchhspeedButton = uitogglebutton(app.WingtypeButtonGroup);
            app.HawfinchhspeedButton.Text = 'Hawfinch h-speed';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BirdWing_2017b

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LiftDragMeasurementUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LiftDragMeasurementUIFigure)
        end
    end
end